classdef BrainAligner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        yShift                     matlab.ui.control.Slider
        xShift                     matlab.ui.control.Slider
        cAlpha                     matlab.ui.control.Slider
        cRotate                    matlab.ui.control.Slider
        cFrame                     matlab.ui.control.Slider
        resetFrame                 matlab.ui.control.Button
        hideFrame                  matlab.ui.control.StateButton
        NextButton                 matlab.ui.control.Button
        BeforeButton               matlab.ui.control.Button
        adjustColor                matlab.ui.control.Slider
        MagnificationSpinnerLabel  matlab.ui.control.Label
        cScale                     matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        data % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, alignIm, imStack, shiftVals, rotVals, scaleVals)
            app.data.alignIm = gather(alignIm);
            app.data.imStack = gather(imStack);
            imagesc(app.UIAxes, app.data.alignIm);
            hold(app.UIAxes, 'on');
            colormap(app.UIAxes, 'gray');
            axis(app.UIAxes, 'image');
            
            app.cFrame.Limits = [1 size(imStack,3)];
            app.cFrame.MajorTicks = 1 : size(imStack,3);
            cIm = imagesc(app.UIAxes, app.data.imStack(:,:,app.cFrame.Value));
            cIm.AlphaData = app.cAlpha.Value;
            
            app.data.shift = zeros(2, size(imStack,3));
            app.data.rotate = zeros(1, size(imStack,3));
            app.data.scale = ones(1, size(imStack,3));
            
            if exist('shiftVals', 'var')
                if size(shiftVals,2) == size(imStack,3)
                    app.data.shift = double(shiftVals);
                end
            end
            if exist('rotVals', 'var')
                if size(rotVals,2) == size(imStack,3)
                    app.data.rotate = double(rotVals);
                end
            end
            if exist('scaleVals', 'var')
                if size(scaleVals,2) == size(imStack,3)
                    app.data.scale = double(scaleVals);
                end
            end
            cFrameValueChanged(app)
        end

        % Value changed function: yShift
        function yShiftValueChanged(app, event)
            app.data.shift(2, app.cFrame.Value) = app.yShift.Value;
            cFrameValueChanged(app, event)
        end

        % Value changed function: xShift
        function xShiftValueChanged(app, event)
            app.data.shift(1, app.cFrame.Value) = app.xShift.Value;
            cFrameValueChanged(app, event)
        end

        % Value changed function: cAlpha
        function cAlphaValueChanged(app, event)
            app.UIAxes.Children(1).AlphaData = app.cAlpha.Value;
        end

        % Value changed function: cFrame
        function cFrameValueChanged(app, event)
            value = round(app.cFrame.Value);
            app.cFrame.Value = value;
            app.yShift.Value = app.data.shift(2, app.cFrame.Value);
            app.xShift.Value = app.data.shift(1, app.cFrame.Value);
            
            if app.data.rotate(app.cFrame.Value) > max(app.cRotate.Limits)
                app.data.rotate(app.cFrame.Value) = max(app.cRotate.Limits);
            elseif app.data.rotate(app.cFrame.Value) < min(app.cRotate.Limits)
                app.data.rotate(app.cFrame.Value) = min(app.cRotate.Limits);
            end
            
            app.cRotate.Value = app.data.rotate(app.cFrame.Value);
            app.cScale.Value = app.data.scale(app.cFrame.Value);
            
            if length(app.UIAxes.Children) > 1
                delete(app.UIAxes.Children(1));
            end
            
            params.angleD = app.cRotate.Value;
            params.tC = [app.xShift.Value, app.yShift.Value]';
            params.scaleConst = app.cScale.Value;
            cIm = imagesc(app.UIAxes, alignAllenTransIm(app.data.imStack(:,:,app.cFrame.Value), params));
            cIm.AlphaData = app.cAlpha.Value;
            
            app.UIAxes.Children(1).Visible = ~app.hideFrame.Value;
            adjustColorValueChanged(app);
            
            assignin('base', 'appShift', app.data.shift);
            assignin('base', 'appRotate', app.data.rotate);
            assignin('base', 'appScale', app.data.scale);

        end

        % Value changed function: hideFrame
        function hideFrameValueChanged(app, event)
            app.UIAxes.Children(1).Visible = ~app.hideFrame.Value;
            adjustColorValueChanged(app, event);
        end

        % Button pushed function: resetFrame
        function resetFrameButtonPushed(app, event)
            app.data.shift(:, app.cFrame.Value) = [0 0];
            app.data.rotate(app.cFrame.Value) = 0;
            app.data.scale(app.cFrame.Value) = 1;
            cFrameValueChanged(app, event);
        end

        % Value changed function: cRotate
        function cRotateValueChanged(app, event)
            app.data.rotate(app.cFrame.Value) = app.cRotate.Value;
            cFrameValueChanged(app, event)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.cFrame.Value + 1 <= max(app.cFrame.Limits)
                app.cFrame.Value = app.cFrame.Value + 1;
                cFrameValueChanged(app, event);
            end
        end

        % Button pushed function: BeforeButton
        function BeforeButtonPushed(app, event)
            if app.cFrame.Value - 1 >= 1
                app.cFrame.Value = app.cFrame.Value - 1;
                cFrameValueChanged(app, event);
            end
        end

        % Value changed function: adjustColor
        function adjustColorValueChanged(app, event)
            if strcmpi(app.UIAxes.Children(1).Visible, 'on')
                caxis(app.UIAxes,[min(app.UIAxes.Children(1).CData(:)) max(app.UIAxes.Children(1).CData(:))*app.adjustColor.Value]);
            else
                caxis(app.UIAxes,[min(app.UIAxes.Children(end).CData(:)) max(app.UIAxes.Children(end).CData(:))*app.adjustColor.Value]);
            end
        end

        % Value changed function: cScale
        function cScaleValueChanged(app, event)
            app.data.scale(app.cFrame.Value) = app.cScale.Value;
            cFrameValueChanged(app, event);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            if strcmp(key,'space') || strcmp(key,'rightarrow') 
                NextButtonPushed(app, event)
            elseif strcmp(key,'leftarrow') 
                BeforeButtonPushed(app, event)
            elseif strcmp(key,'a')
                app.xShift.Value = app.xShift.Value - 0.25;
                xShiftValueChanged(app, event);
            elseif strcmp(key,'d')
                app.xShift.Value = app.xShift.Value + 0.25;
                xShiftValueChanged(app, event);
            elseif strcmp(key,'w')
                app.yShift.Value = app.yShift.Value - 0.25;
                yShiftValueChanged(app, event);
            elseif strcmp(key,'s')
                app.yShift.Value = app.yShift.Value + 0.25;
                yShiftValueChanged(app, event);                
            elseif strcmp(key,'r')
                app.cScale.Value = app.cScale.Value + 0.0025;
                cScaleValueChanged(app, event);         
            elseif strcmp(key,'f')
                app.cScale.Value = app.cScale.Value - 0.0025;
                cScaleValueChanged(app, event);  
            elseif strcmp(key,'q')
                app.cRotate.Value = app.cRotate.Value - 0.05;
                cRotateValueChanged(app, event);  
            elseif strcmp(key,'e')
                app.cRotate.Value = app.cRotate.Value + 0.05;
                cRotateValueChanged(app, event);
            elseif strcmp(key,'shift')
                app.hideFrame.Value = ~app.hideFrame.Value;
                hideFrameValueChanged(app, event);
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'BrainAligner')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.yShift = uislider(app.UIFigure);
            app.yShift.Limits = [-150 150];
            app.yShift.MajorTicks = [];
            app.yShift.ValueChangedFcn = createCallbackFcn(app, @yShiftValueChanged, true);
            app.yShift.MinorTicks = [];
            app.xShift = uislider(app.UIFigure);
            app.xShift.Limits = [-150 150];
            app.xShift.MajorTicks = [];
            app.xShift.ValueChangedFcn = createCallbackFcn(app, @xShiftValueChanged, true);
            app.xShift.MinorTicks = [];
            app.cAlpha = uislider(app.UIFigure);
            app.cAlpha.Limits = [0 1];
            app.cAlpha.MajorTicks = [];
            app.cAlpha.MajorTickLabels = {'Alpha'};
            app.cAlpha.ValueChangedFcn = createCallbackFcn(app, @cAlphaValueChanged, true);
            app.cAlpha.MinorTicks = [];
            app.cAlpha.Value = 1;
            app.cRotate = uislider(app.UIFigure);
            app.cRotate.Limits = [-80 80];
            app.cRotate.MajorTicks = [];
            app.cRotate.ValueChangedFcn = createCallbackFcn(app, @cRotateValueChanged, true);
            app.cRotate.MinorTicks = [];
            app.cFrame = uislider(app.UIFigure);
            app.cFrame.Limits = [1 10];
            app.cFrame.ValueChangedFcn = createCallbackFcn(app, @cFrameValueChanged, true);
            app.cFrame.MinorTicks = [];
            app.cFrame.Value = 1;
            app.resetFrame = uibutton(app.UIFigure, 'push');
            app.resetFrame.ButtonPushedFcn = createCallbackFcn(app, @resetFrameButtonPushed, true);
            app.resetFrame.Text = 'Reset frame';
            app.hideFrame = uibutton(app.UIFigure, 'state');
            app.hideFrame.ValueChangedFcn = createCallbackFcn(app, @hideFrameValueChanged, true);
            app.hideFrame.Text = 'Hide frame';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BeforeButton = uibutton(app.UIFigure, 'push');
            app.BeforeButton.ButtonPushedFcn = createCallbackFcn(app, @BeforeButtonPushed, true);
            app.BeforeButton.Text = 'Before';
            app.adjustColor = uislider(app.UIFigure);
            app.adjustColor.Limits = [0 1];
            app.adjustColor.MajorTicks = [];
            app.adjustColor.MajorTickLabels = {'Alpha'};
            app.adjustColor.ValueChangedFcn = createCallbackFcn(app, @adjustColorValueChanged, true);
            app.adjustColor.MinorTicks = [];
            app.adjustColor.Value = 1;
            app.MagnificationSpinnerLabel = uilabel(app.UIFigure);
            app.MagnificationSpinnerLabel.Text = 'Magnification';
            app.cScale = uispinner(app.UIFigure);
            app.cScale.Step = 0.05;
            app.cScale.ValueChangedFcn = createCallbackFcn(app, @cScaleValueChanged, true);
            app.cScale.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = BrainAligner(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end